classdef Mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ProcessTab                   matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        ProcessPanel                 matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        Lamp                         matlab.ui.control.Lamp
        RUNButton                    matlab.ui.control.Button
        DeformationMagnitude         matlab.ui.control.NumericEditField
        DeformationMagnitudeEditFieldLabel  matlab.ui.control.Label
        DeformationTypeImage         matlab.ui.control.Image
        DeformationTypeButtonGroup   matlab.ui.container.ButtonGroup
        ShearButton                  matlab.ui.control.ToggleButton
        ExpansionButton              matlab.ui.control.ToggleButton
        ExtensionButton              matlab.ui.control.ToggleButton
        Incv                         matlab.ui.control.NumericEditField
        vEditField_2Label            matlab.ui.control.Label
        Matrixv                      matlab.ui.control.NumericEditField
        vEditFieldLabel              matlab.ui.control.Label
        IncE                         matlab.ui.control.NumericEditField
        EEditField_2Label            matlab.ui.control.Label
        MatrixE                      matlab.ui.control.NumericEditField
        EEditFieldLabel              matlab.ui.control.Label
        InclusionLabel               matlab.ui.control.Label
        MatrixLabel                  matlab.ui.control.Label
        MaterialPropertiesLabel      matlab.ui.control.Label
        PreProcessPanel              matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        DimensionsandNumberofPartitionsLabel  matlab.ui.control.Label
        eEditField                   matlab.ui.control.NumericEditField
        eEditFieldLabel              matlab.ui.control.Label
        dEditField                   matlab.ui.control.NumericEditField
        dEditFieldLabel              matlab.ui.control.Label
        cEditField                   matlab.ui.control.NumericEditField
        cEditFieldLabel              matlab.ui.control.Label
        bEditField                   matlab.ui.control.NumericEditField
        bEditFieldLabel              matlab.ui.control.Label
        aEditField                   matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        IncSwitch                    matlab.ui.control.Switch
        ErrorField                   matlab.ui.control.Label
        NodesEditField               matlab.ui.control.NumericEditField
        NodesEditFieldLabel          matlab.ui.control.Label
        ElementsEditField            matlab.ui.control.NumericEditField
        ElementsEditFieldLabel       matlab.ui.control.Label
        ResetButton                  matlab.ui.control.Button
        GenerateButton               matlab.ui.control.Button
        ElementType                  matlab.ui.control.DropDown
        ElementTypeDropDownLabel     matlab.ui.control.Label
        InclusionShapeButtonGroup    matlab.ui.container.ButtonGroup
        CircleButton                 matlab.ui.control.ToggleButton
        SquareButton                 matlab.ui.control.ToggleButton
        RhombusButton                matlab.ui.control.ToggleButton
        ShapeImage                   matlab.ui.control.Image
        PreProcessAxis               matlab.ui.control.UIAxes
        PostProcessTab               matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        PostProcessPanel             matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        DisplayEdgesSwitch           matlab.ui.control.RockerSwitch
        DisplayEdgesSwitchLabel      matlab.ui.control.Label
        DisplayValuesDropDown        matlab.ui.control.DropDown
        DisplayValuesDropDownLabel   matlab.ui.control.Label
        RESETALLButton               matlab.ui.control.Button
        ColormapGroup                matlab.ui.container.ButtonGroup
        Copper                       matlab.ui.control.ToggleButton
        Cool                         matlab.ui.control.ToggleButton
        Jet                          matlab.ui.control.ToggleButton
        DeformationScaleSlider       matlab.ui.control.Slider
        DeformationScaleSliderLabel  matlab.ui.control.Label
        PostProcessAxis              matlab.ui.control.UIAxes
    end

    %Red: 1.00,0.00,0.00
    %Yellow: 0.94,0.72,0.00
    %Green: 0.21,0.91,0.21
    %Grey: 0.65,0.65,0.65
    properties (Access = public)
        Property % Description
        inclusionShape='circle';
        NL=[];
        EL=[];
        ENL=[];
        BC_type='Extension';
        inc=1;
        uStress_xx = [];
        uStress_xy = [];
        uStress_yx = [];
        uStress_yy = [];
        uStrain_xx = [];
        uStrain_xy = [];
        uStrain_yx = [];
        uStrain_yy = [];
        uDisplacement_x = [];
        uDisplacement_y = [];
        uX = [];
        uY = [];
        colorMap='jet';
        displayEdges='-';
        E_void_run=0;
        nu_void_run=0;
        E_inc_run=0;
        nu_inc_run=0;
        postNL = [];
        postEL = [];
        postc = 0;
        postd = 0;
        postinc = 0;
        runDone = "False";
        generateDone = "False"
        comparison1_values = 0
        comparison2_values = 0
        c21=0;
        c22=0;
        c23= 0;
        c24=0;
        c25=0;
        c26 =0;
        c27=0;
        c28 = 0;
        c29 =0;
        c210 =0;
        c211 =0;
        c212=0 ;
        c213 =0;

        c11=0;
        c12=0;
        c13= 0;
        c14=0;
        c15=0;
        c16 =0;
        c17=0;
        c18 = 0;
        c19 =0;
        c110 =0;
        c111 =0;
        c112=0 ;
        c113 = 0;


    end
    
    methods (Access = public)

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%% Boundary Conditions %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
    function [ENL,DOFs,DOCs] = assign_BCs( app,NL , BC_type) 
    
        deformationVal=app.DeformationMagnitude.Value;
        
        NoN = size(NL,1);
        PD = size(NL,2);
        
        ENL = zeros(NoN,6*PD);
        ENL(:,1:PD) = NL;

        switch BC_type
    
        case 'Extension'
            for i = 1:NoN
                if ( ENL(i,1)== 0 ) %Node is on the left side of the domain
                    ENL(i,3) = -1; %Dirichlet x
                    ENL(i,4) = -1; %Dirichlet y
                    
                    ENL(i,9) =  0; %x displacement
                    ENL(i,10) = 0; %y displacement                
                elseif (ENL(i,1) == app.aEditField.Value) %Node is on the right side corner
                    ENL(i,3) = -1;
                    ENL(i,4) = -1;
                    
                    ENL(i,9) = deformationVal;
                    ENL(i,10) = 0;
                else 
                    ENL(i,3) = 1;
                    ENL(i,4) = 1;
                    
                    ENL(i,11) = 0; %Force in x 
                    ENL(i,12) = 0; %Force in y                     
                end
            end
        case 'Expansion' 
            for i = 1:NoN
                if ( ENL(i,1)== 0  || ENL(i,1) == app.aEditField.Value || ENL(i,2) == 0 || ENL(i,2) == app.bEditField.Value)
                    ENL(i,3) = -1; %Dirichlet x
                    ENL(i,4) = -1; %Dirichlet y

                    ENL(i,9) = deformationVal*ENL(i,1); %x displacement 
                    ENL(i,10) = deformationVal*ENL(i,2); %y displacement
                else
                    ENL(i,3) = 1;
                    ENL(i,4) = 1;

                    ENL(i,11) = 0; % Force in x 
                    ENL(i,12) = 0; %Force in y 
                end
            end      
        case 'Shear' 
            for i = 1:NoN
                if ( ENL(i,2)== 0 ) %bottom side
                    ENL(i,3) = -1; %Dirichlet x
                    ENL(i,4) = -1; %Dirichlet y
        
                    ENL(i,9) = 0; %x displacement 
                    ENL(i,10) = 0; %y displacement
                elseif (ENL(i,2) == app.bEditField.Value) %Top side of node
                
                    ENL(i,3) = -1;
                    ENL(i,4) = -1; 
                
                    ENL(i,9) = deformationVal; 
                    ENL(i,10) = 0;

                else %All Neumann nodes
                
                    ENL(i,3) = 1;
                    ENL(i,4) = 1; 
                    
                    ENL(i,11) = 0; %Force in x 
                    ENL(i,12) = 0; %Force in y 

                end
            end
        end

        DOFs = 0;
        DOCs = 0;
        
        for i = 1:NoN 
            for j = 1:PD
                if(ENL(i,j+PD) == -1)
                    DOCs = DOCs -1;
                    ENL(i,j+2*PD) = DOCs; 
                else 
                    DOFs = DOFs +1;
                    ENL(i,2*PD+j) = DOFs;
                end              
            end                      
        end

        DOCs = abs(DOCs);        
        for i = 1:NoN                 
            for j = 1:PD
                if ( ENL(i,2*PD+j)<0 ) %if negative numbers                    
                    ENL(i,3*PD+j) = abs(ENL(i,2*PD+j)) + DOFs;                
                else 
                    ENL(i,3*PD+j) = ENL(i,2*PD+j);
                end
            end       
        end
    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%% ELEMENT STIFFNESS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function output  = element_stiffness(app,nl , NL, element_number,NoE_void,inclusion)
            NPE = size(nl,2); %4 for D2QU4N, 3 for D2TR3N %::Node Per Element (x= corner coordinates)::%
            PD = size(NL,2); %2
         
            x = zeros(NPE,PD);
            x(1:NPE,1:PD) = NL(nl(1:NPE),(1:PD));   
            
            coor = x';        
            if ( NPE == 3 )
                GPE = 1;
                
            elseif (NPE == 4)
                GPE = 4;
            elseif (NPE == 6)
                GPE = 3;
            elseif (NPE == 8 || NPE == 9)
                GPE = 9;
            end    
        
            K = zeros(NPE*PD,NPE*PD);
            for i=1:NPE
                for j = 1:NPE
                    k = zeros(PD,PD);
                    for gp = 1:GPE
                        J = zeros(PD,PD);
                        grad = zeros(PD,NPE); 
                        [xi , eta , alpha] = GaussPoint(app,NPE , GPE , gp);
                        grad_nat = grad_N_nat(app,NPE , xi , eta);
                        J = coor * grad_nat' ;
                        grad= inv(J)' * grad_nat;
                        for a = 1:PD
                            for c = 1:PD
                                for b = 1:PD
                                    for d = 1:PD
                                        if NPE ==3 || NPE == 6
        %                                   k(a,c) = k(a,c) + grad(b,i) * constitutive(a,b,c,d) * grad(d,j) * det(J) * alpha; %%: for grad b for column i for for:%%
                                          k(a,c) = k(a,c) + grad(b,i) * constitutive(app,a,b,c,d,inclusion,element_number,NoE_void) * grad(d,j) * det(J) * alpha * 0.5 ;
                                        else
                                          k(a,c) = k(a,c) + grad(b,i) * constitutive(app,a,b,c,d,inclusion,element_number,NoE_void) * grad(d,j) * det(J) * alpha ;
                                        end     
                                        
                                    end
                                end    
                            end
                        end
                    end
                    K( ((i-1)*PD+1):i*PD , ((j-1)*PD+1):j*PD ) = k ;
                end
            end
            output = K;
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%% Gauss Point per Element %%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function [xi, eta, alpha] = GaussPoint(app,NPE, GPE, gp)
    
            if (NPE == 4) %quadrilateral
                if (GPE == 1)            
                    if (gp == 1)
                       xi = 0; eta =0; alpha =4; 
                    end
                elseif (GPE == 4)
                    if (gp == 1)
                        xi = -1/sqrt(3); eta = -1/sqrt(3); alpha = 1; 
                    elseif (gp == 2)
                        xi = 1/sqrt(3); eta = -1/sqrt(3); alpha = 1;
                    elseif (gp == 3)
                        xi = 1/sqrt(3);  eta = 1/sqrt(3); alpha = 1;
                    elseif (gp == 4)
                        xi = -1/sqrt(3); eta = 1/sqrt(3); alpha = 1;
                    end
                end
            elseif (NPE == 3) %triangular 
                if (gp == 1)
                        xi = 1/3; eta = 1/3; alpha = 1;      
                end
            elseif (NPE == 6)
                if (GPE == 1)
                    if (gp == 1)
                      xi = 1/3; eta = 1/3; alpha = 1; 
                    end
                elseif (GPE == 3)                    
                    if (gp == 1)
                        xi = 1/6; eta = 1/6; alpha = 1/3; 
                    elseif (gp == 2)
                        xi = 4/6; eta = 1/6; alpha = 1/3;
                    elseif (gp == 3)
                        xi = 1/6;  eta = 4/6; alpha = 1/3;
                    end               
                end                
            elseif (NPE == 8 || NPE == 9)
                if (GPE == 1)
                    if (gp == 1)
                       xi = 0; eta =0; alpha =4; 
                    end
                elseif (GPE == 4)
                    if (gp == 1)
                        xi = -1/sqrt(3); eta = -1/sqrt(3); alpha = 1; 
                    elseif (gp == 2)
                        xi = 1/sqrt(3); eta = -1/sqrt(3); alpha = 1;
                    elseif (gp == 3)
                        xi = 1/sqrt(3);  eta = 1/sqrt(3); alpha = 1;
                    elseif (gp == 4)
                        xi = -1/sqrt(3); eta = 1/sqrt(3); alpha = 1;
                    end
                elseif (GPE == 9)
                    if (gp == 1)
                        xi = -sqrt(3/5); eta = -sqrt(3/5); alpha = (5/9)*(5/9); 
                    elseif (gp == 2)
                        xi = sqrt(3/5); eta = -sqrt(3/5); alpha = (5/9)*(5/9);
                    elseif (gp == 3)
                        xi = sqrt(3/5);  eta = sqrt(3/5); alpha = (5/9)*(5/9);
                    elseif (gp == 4)
                        xi = -sqrt(3/5); eta = sqrt(3/5); alpha = (5/9)*(5/9);
                    elseif (gp == 5)
                        xi = 0; eta = -sqrt(3/5); alpha = (5/9)*(8/9);
                    elseif (gp == 6)
                        xi = sqrt(3/5); eta = 0; alpha = (5/9)*(8/9);
                    elseif (gp == 7)
                        xi = 0; eta = sqrt(3/5); alpha = (5/9)*(8/9);
                    elseif (gp == 8)
                        xi = -sqrt(3/5); eta = 0; alpha = (5/9)*(8/9);
                    elseif (gp == 9)
                        xi = 0; eta = 0; alpha = (8/9)*(8/9);
                    end
                end
            end
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%% Gradient of Shape fnc %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function result = grad_N_nat(app, NPE, xi , eta)
            PD = 2;
            result = zeros(PD,NPE);
            if (NPE == 3) %triangular
                result(1,1) = 1;
                result(1,2) = 0;
                result(1,3) = -1;
                        
                result(2,1) = 0;
                result(2,2) = 1;
                result(2,3) = -1;

            elseif (NPE == 4) %quadrilateral
                result(1,1) = -1/4*(1-eta);
                result(1,2) = 1/4*(1-eta);
                result(1,3) = 1/4*(1+eta);
                result(1,4) = -1/4*(1+eta);
                
                result(2,1) = -1/4*(1-xi);
                result(2,2) = -1/4*(1+xi);
                result(2,3) = 1/4*(1+xi);
                result(2,4) = 1/4*(1-xi);
        
            elseif (NPE == 6)
                result(1,3) = -1 + (4*xi);
                result(1,1) = 0;
                result(1,2) = -3 + (4*xi) + (4*eta);
                result(1,4) = 4*eta;
                result(1,5) = -(4*eta);
                result(1,6) = (-4)*((-1)+eta+(2*xi));
                
                result(2,3) = 0;
                result(2,1) = -1+(4*eta);
                result(2,2) = (-3) + (4*xi) + (4*eta);
                result(2,4) = 4*xi;
                result(2,5) = (-4) * ((-1) + (2*eta) + xi);
                result(2,6) = -4*(xi);
        
            elseif (NPE == 8)
                result(1,1) = (1/4) * (1-eta)*(2*xi + eta);
                result(1,2) = (1/4) * (1-eta)*(2*xi - eta);
                result(1,3) = (1/4) * (1+eta)*(2*xi + eta);
                result(1,4) = (1/4) * (1+eta)*(2*xi - eta);
                result(1,5) = -xi* (1-eta);
                result(1,6) = (1/2) * (1-eta)*(1+eta);
                result(1,7) = -xi*(1+eta);
                result(1,8) = -(1/2) * (1-eta)*(1+eta);
        
                result(2,1) = (1/4) * (1-xi)*(xi + 2*eta);
                result(2,2) = (1/4) * (1+xi)*(-xi + 2*eta);
                result(2,3) = (1/4) * (1+xi)*(xi + 2*eta);
                result(2,4) = (1/4) * (1-xi)*(-xi + 2*eta);
                result(2,5) = -(1/2) * (1-xi)*(1+xi);
                result(2,6) = -(1+xi)*eta;
                result(2,7) = (1/2) * (1-xi)*(1+xi);
                result(2,8) = -(1-xi)*eta;
        
            elseif (NPE == 9)
                result(1,1) = 1/4*(1-(2*xi))*(1-eta)*eta;
                result(1,2) = -1/4*(1+(2*xi))*(1-eta)*eta;
                result(1,3) = 1/4*(1+(2*xi))*(1+eta)*eta;
                result(1,4) = -1/4*(1-(2*xi))*(1+eta)*eta;
                result(1,5) = xi*eta*(1-eta);
                result(1,6) = 1/2*(1+(2*xi))*(1-eta)*(1+eta);
                result(1,7) = -xi*eta*(1+eta);
                result(1,8) = -1/2*(1-(2*xi))*(1-eta)*(1+eta);
                result(1,9) = -2*xi*(1-eta)*(1+eta);
        
        
                result(2,1) = 1/4*(1-xi)*xi*(1-(2*eta));
                result(2,2) = -1/4*(1+xi)*xi*(1-(2*eta));
                result(2,3) = 1/4*(1+xi)*xi*(1+(2*eta));
                result(2,4) = -1/4*(1-xi)*xi*(1+(2*eta));
                result(2,5) = 1/2*(1-xi)*(1+xi)*((2*eta)-1);
                result(2,6) = -(1+xi)*xi*eta;
                result(2,7) = 1/2*(1-xi)*(1+xi)*(1+(2*eta));
                result(2,8) = (1-xi)*xi*eta;
                result(2,9) = -2*(1-xi)*(1+xi)*eta;
        
            end
        end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%% Constitutive %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        function C = constitutive(app,i,j,k,l,inclusion,element_number,NoE_void)
            if (inclusion == 1) && element_number > NoE_void
                E =app.IncE.Value;
                nu =app.Incv.Value;
            else
                E=app.MatrixE.Value;
                nu = app.Matrixv.Value ;
            end
                
                C = (E/(2*(1+nu))) * (delta(app,i,l)*delta(app,j,k) + delta(app,i,k)*delta(app,j,l)) + (E*nu)/(1-nu^2) * delta(app,i,j)*delta(app,k,l); 
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%% Delta Function %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function delta = delta(app,i,j)
            delta=isequal(i,j);
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%% Stiffness %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   
        function [K] = assemble_stiffness(app, ENL ,EL , NL , p , m , inclusion )
            NoE = size(EL,1);
            NPE = size(EL,2);
            if (NPE == 4) || (NPE == 9) || (NPE == 8)
                NoE_void = 4*p*m;
            else
                NoE_void = 8*p*m;
            end
            NoN = size(NL,1);
            PD = size(NL,2);
            K = zeros(NoN*PD, NoN*PD); % Stiffness matrix of all elements
            
            for i = 1:NoE % Calculate stiffness of each element 
                nl = EL(i,1:NPE); 
                element_number = i;
                k = element_stiffness(app,nl,NL,element_number,NoE_void,inclusion);
                for r = 1:NPE
                    for p = 1:PD
                        for q = 1:NPE
                            for s = 1:PD
                                row = ENL(nl(r), p+3*PD);
                                column = ENL(nl(q), s+3*PD);
                                value = k( (r-1)*PD + p, (q-1)*PD + s );
                                
                                K(row,column) = K(row,column) + value;
                            end
                        end
                    end
                end
            end
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%% POST PROCESSS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function [] = post_process(app,NL,EL,ENL,p,m,inclusion,indicator) 
            NoN = size(NL,1);
            PD = size(NL,2);
            NoE = size(EL,1);
            NPE = size(EL,2);
            if (NPE == 4) || (NPE == 9) || (NPE == 8)
                NoE_void = 4*p*m;
            else
                NoE_void = 8*p*m;
            end
            %Plot deformation
            scale = 10^(app.DeformationScaleSlider.Value); 
            
            [disp,stress,strain] = element_post_process(app,NL,EL,ENL,inclusion,NoE_void);
            
            switch NPE %include D2TR3N
                case 3  %1 GP
                    X=zeros(NPE,NoE);
                    Y=zeros(NPE,NoE);
                    for i = 1:NoE
                        %37:58
                        nl  = EL(i,1:NPE);
                        X(1:NPE,i) = ENL(nl(1:NPE),1) + scale*ENL(nl(1:NPE),4*PD+1);
                        Y(1:NPE,i) = ENL(nl(1:NPE),2) + scale*ENL(nl(1:NPE),4*PD+2); 
%open the for loops to calculate faster
                        stress_xx(1:NPE,i) = stress(i,:,1,1);
                        stress_xy(1:NPE,i) = stress(i,:,1,2);        
                        stress_yx(1:NPE,i) = stress(i,:,2,1);
                        stress_yy(1:NPE,i) = stress(i,:,2,2);
                    
                        strain_xx(1:NPE,i) = strain(i,:,1,1);  
                        strain_xy(1:NPE,i) = strain(i,:,1,2);
                        strain_yx(1:NPE,i) = strain(i,:,2,1);
                        strain_yy(1:NPE,i) = strain(i,:,2,2);
        
                        disp_x(1:NPE,i) = disp(i,1:NPE,1,1);
                        disp_y(1:NPE,i) = disp(i,1:NPE,2,1);
        
                    end
                case 8
            
                    val(1:NoE,:) = stress(1:NoE,:,1,1);
                    stress_xx(1,1:NoE)=val(1:NoE,1);
                    stress_xx(2,1:NoE)=val(1:NoE,5);
                    stress_xx(3,1:NoE)=val(1:NoE,2);
                    stress_xx(4,1:NoE)=val(1:NoE,6);
                    stress_xx(5,1:NoE)=val(1:NoE,3);
                    stress_xx(6,1:NoE)=val(1:NoE,7);
                    stress_xx(7,1:NoE)=val(1:NoE,4);
                    stress_xx(8,1:NoE)=val(1:NoE,8);
                    
                    val = stress(1:NoE,:,1,2); 
                    stress_xy(1,1:NoE)=val(1:NoE,1);
                    stress_xy(2,1:NoE)=val(1:NoE,5);
                    stress_xy(3,1:NoE)=val(1:NoE,2);
                    stress_xy(4,1:NoE)=val(1:NoE,6);
                    stress_xy(5,1:NoE)=val(1:NoE,3);
                    stress_xy(6,1:NoE)=val(1:NoE,7);
                    stress_xy(7,1:NoE)=val(1:NoE,4);
                    stress_xy(8,1:NoE)=val(1:NoE,8);
                    
                    val = stress(1:NoE,:,2,1); 
                    stress_yx(1,1:NoE)=val(1:NoE,1);
                    stress_yx(2,1:NoE)=val(1:NoE,5);
                    stress_yx(3,1:NoE)=val(1:NoE,2);
                    stress_yx(4,1:NoE)=val(1:NoE,6);
                    stress_yx(5,1:NoE)=val(1:NoE,3);
                    stress_yx(6,1:NoE)=val(1:NoE,7);
                    stress_yx(7,1:NoE)=val(1:NoE,4);
                    stress_yx(8,1:NoE)=val(1:NoE,8);
                    
                    val = stress(1:NoE,:,2,2); 
                    stress_yy(1,1:NoE)=val(1:NoE,1);
                    stress_yy(2,1:NoE)=val(1:NoE,5);
                    stress_yy(3,1:NoE)=val(1:NoE,2);
                    stress_yy(4,1:NoE)=val(1:NoE,6);
                    stress_yy(5,1:NoE)=val(1:NoE,3);
                    stress_yy(6,1:NoE)=val(1:NoE,7);
                    stress_yy(7,1:NoE)=val(1:NoE,4);
                    stress_yy(8,1:NoE)=val(1:NoE,8);
                    
                               
                    val = strain(1:NoE,:,1,1); 
                    strain_xx(1,1:NoE)=val(1:NoE,1);
                    strain_xx(2,1:NoE)=val(1:NoE,5);
                    strain_xx(3,1:NoE)=val(1:NoE,2);
                    strain_xx(4,1:NoE)=val(1:NoE,6);
                    strain_xx(5,1:NoE)=val(1:NoE,3);
                    strain_xx(6,1:NoE)=val(1:NoE,7);
                    strain_xx(7,1:NoE)=val(1:NoE,4);
                    strain_xx(8,1:NoE)=val(1:NoE,8);
                    
                    val = strain(1:NoE,:,1,2); 
                    strain_xy(1,1:NoE)=val(1:NoE,1);
                    strain_xy(2,1:NoE)=val(1:NoE,5);
                    strain_xy(3,1:NoE)=val(1:NoE,2);
                    strain_xy(4,1:NoE)=val(1:NoE,6);
                    strain_xy(5,1:NoE)=val(1:NoE,3);
                    strain_xy(6,1:NoE)=val(1:NoE,7);
                    strain_xy(7,1:NoE)=val(1:NoE,4);
                    strain_xy(8,1:NoE)=val(1:NoE,8);
                    
                    val = strain(1:NoE,:,2,1); 
                    strain_yx(1,1:NoE)=val(1:NoE,1);
                    strain_yx(2,1:NoE)=val(1:NoE,5);
                    strain_yx(3,1:NoE)=val(1:NoE,2);
                    strain_yx(4,1:NoE)=val(1:NoE,6);
                    strain_yx(5,1:NoE)=val(1:NoE,3);
                    strain_yx(6,1:NoE)=val(1:NoE,7);
                    strain_yx(7,1:NoE)=val(1:NoE,4);
                    strain_yx(8,1:NoE)=val(1:NoE,8);
                    
                    val = strain(1:NoE,:,2,2); 
                    strain_yy(1,1:NoE)=val(1:NoE,1);
                    strain_yy(2,1:NoE)=val(1:NoE,5);
                    strain_yy(3,1:NoE)=val(1:NoE,2);
                    strain_yy(4,1:NoE)=val(1:NoE,6);
                    strain_yy(5,1:NoE)=val(1:NoE,3);
                    strain_yy(6,1:NoE)=val(1:NoE,7);
                    strain_yy(7,1:NoE)=val(1:NoE,4);
                    strain_yy(8,1:NoE)=val(1:NoE,8);
                    
                    rank=[1,5,2,6,3,7,4,8];
     
                    for i = 1:NoE
                        nl  = EL(i,1:NPE);
                        X(1:NPE,i) = ENL(nl(rank),1) + scale*ENL(nl(rank),4*PD+1);
                        Y(1:NPE,i) = ENL(nl(rank),2) + scale*ENL(nl(rank),4*PD+2);
                        
                        disp_x(1,i) = disp(i,1,1,1);
                        disp_x(2,i) = disp(i,5,1,1);
                        disp_x(3,i) = disp(i,2,1,1);
                        disp_x(4,i) = disp(i,6,1,1);
                        disp_x(5,i) = disp(i,3,1,1);
                        disp_x(6,i) = disp(i,7,1,1);
                        disp_x(7,i) = disp(i,4,1,1);
                        disp_x(8,i) = disp(i,8,1,1);
            
                        disp_y(1,i) = disp(i,1,2,1);
                        disp_y(2,i) = disp(i,5,2,1);
                        disp_y(3,i) = disp(i,2,2,1);
                        disp_y(4,i) = disp(i,6,2,1);
                        disp_y(5,i) = disp(i,3,2,1);
                        disp_y(6,i) = disp(i,7,2,1);
                        disp_y(7,i) = disp(i,4,2,1);
                        disp_y(8,i) = disp(i,8,2,1);
            
                    end 
                case 4
                    
                   for i = 1:NoE
                        nl  = EL(i,1:NPE);
                        for j = 1:NPE 
                            X(j,i) = ENL(nl(j),1) + scale*ENL(nl(j),4*PD+1); 
                            Y(j,i) = ENL(nl(j),2) + scale*ENL(nl(j),4*PD+2); 
                        end
                        for j = 1:NPE
                            val = stress(i,:,1,1); 
                            stress_xx(j,i) = val(1,j); 
                        end
                        for j = 1:NPE
                            val = stress(i,:,1,2); 
                            stress_xy(j,i) = val(1,j);
                        end
                        for j = 1:NPE
                            val = stress(i,:,2,1); 
                            stress_yx(j,i) = val(1,j);
                        end
                        for j = 1:NPE
                            val = stress(i,:,2,2); 
                            stress_yy(j,i) = val(1,j);
                        end
                        %Strain
                         for j = 1:NPE
                            val = strain(i,:,1,1); 
                            strain_xx(j,i) = val(1,j);  
                        end
            
                        for j = 1:NPE
                            val = strain(i,:,1,2); 
                            strain_xy(j,i) = val(1,j);
                        end
                        for j = 1:NPE
                            val = strain(i,:,2,1); 
                            strain_yx(j,i) = val(1,j);
                        end
                        for j = 1:NPE
                            val = strain(i,:,2,2); 
                            strain_yy(j,i) = val(1,j);
                        end
                        %Displacement
                        for j = 1:NPE
                            disp_x(j,i) = disp(i,j,1,1);
                        end
                        for j = 1:NPE
                            disp_y(j,i) = disp(i,j,2,1);
                        end
                    end
                 case 9
                   NPE = NPE -1;
                   for i = 1:NoE
                        nl  = EL(i,1:NPE);
                        counter2 = 3;
                        counter1 = 0;
                        for j = 1:NPE 
                            if mod(j,2) == 0
                                X(j,i) = ENL(nl(j+counter2),1) + scale*ENL(nl(j+counter2),4*PD+1); 
                                Y(j,i) = ENL(nl(j+counter2),2) + scale*ENL(nl(j+counter2),4*PD+2);
                                counter2 = counter2 -1;
                            else
                                X(j,i) = ENL(nl(j+counter1),1) + scale*ENL(nl(j+counter1),4*PD+1); 
                                Y(j,i) = ENL(nl(j+counter1),2) + scale*ENL(nl(j+counter1),4*PD+2);
                                counter1 = counter1 -1;
                            end
            
            %             for j = 1:NPE 
            %                 X(j,i) = ENL(nl(j),1) + scale*ENL(nl(j),4*PD+1); 
            %                 Y(j,i) = ENL(nl(j),2) + scale*ENL(nl(j),4*PD+2); 
                        end
                        counter2 = 3;
                        counter1 = 0;
                        
                        for j = 1:NPE
                            if mod(j,2) == 0
                                val = stress(i,:,1,1); %: %:[':' represents all the gausspoints] 1 by 4 matrix,4 values corresponding to xx component of stress at each points 
                                stress_xx(j,i) = val(1,j+counter2); %: j,i to store columnwise
                                counter2 = counter2 -1;
                            else
                                 val = stress(i,:,1,1); %: %:[':' represents all the gausspoints] 1 by 4 matrix,4 values corresponding to xx component of stress at each points 
                                stress_xx(j,i) = val(1,j+counter1); %: j,i to store columnwise
                                counter1 = counter1 -1;
            
                            end
            
                        end
                        counter2 = 3;
                        counter1 = 0;
            
                        for j = 1:NPE
                            if mod(j,2) == 0
                                val = stress(i,:,1,2); 
                                stress_xy(j,i) = val(1,j+counter2);
                                counter2 = counter2 -1;
                            else
                                val = stress(i,:,1,2); 
                                stress_xy(j,i) = val(1,j+counter1);
                                counter1 = counter1-1;
                            end
            
                        end
                        counter2 = 3;
                        counter1 = 0;
                        for j = 1:NPE
                             if mod(j,2) == 0
                                val = stress(i,:,2,1); 
                                stress_yx(j,i) = val(1,j+counter2);
                                counter2 = counter2 - 1;
                             else
                                val = stress(i,:,2,1); 
                                stress_yx(j,i) = val(1,j+counter1);
                                counter1 = counter1 - 1;
                             end
            
                        end
                        counter2 = 3;
                        counter1 = 0;
                        for j = 1:NPE
                            if mod(j,2) == 0
                               val = stress(i,:,2,2); 
                               stress_yy(j,i) = val(1,j+counter2);
                               counter2 = counter2 - 1;
                            else
                               val = stress(i,:,2,2); 
                               stress_yy(j,i) = val(1,j+counter1);
                               counter1 = counter1 - 1;
                            end
            
                        end
            
                        % Strain
                        counter2 = 3;
                        counter1 = 0;
            
                        for j = 1:NPE
                             if mod(j,2) == 0
                                 val = strain(i,:,1,1); 
                                strain_xx(j,i) = val(1,j+counter2);
                                counter2 = counter2 - 1;
                             else
                                val = strain(i,:,1,1); 
                                strain_xx(j,i) = val(1,j+counter1);
                                counter1 = counter1 -1;
                             end
            
                        end
                        counter2 = 3;
                        counter1 = 0;
            
                        for j = 1:NPE
                             if mod(j,2) == 0
                                 val = strain(i,:,1,2); 
                                 strain_xy(j,i) = val(1,j+counter2);
                                 counter2 = counter2 - 1;
                             else
                                 val = strain(i,:,1,2); 
                                 strain_xy(j,i) = val(1,j+counter1);
                                 counter1 = counter1 - 1;
                             end
            
                        end
                        counter2 = 3;
                        counter1 = 0;
                        for j = 1:NPE
                             if mod(j,2) == 0
                                 val = strain(i,:,2,1); 
                                 strain_yx(j,i) = val(1,j+counter2);
                                 counter2 = counter2 - 1;
                             else
                                 val = strain(i,:,2,1); 
                                 strain_yx(j,i) = val(1,j+counter1);
                                 counter1 = counter1 - 1;
                             end
            
                        end
                        counter2 = 3;
                        counter1 = 0;
                        for j = 1:NPE
                            if mod(j,2) == 0
                                val = strain(i,:,2,2); 
                                strain_yy(j,i) = val(1,j+counter2);
                                counter2 = counter2 -1;
                            else
                                val = strain(i,:,2,2); 
                                strain_yy(j,i) = val(1,j+counter1);
                                counter1 = counter1 -1;
                            end
            
                        end
            
                        %Displacement
                        counter2 = 3;
                        counter1 = 0;
                        for j = 1:NPE
                            if mod(j,2) == 0
                               disp_x(j,i) = abs(disp(i,j+counter2,1,1));
                               counter2 = counter2 - 1;
                            else
                               disp_x(j,i) = abs(disp(i,j+counter1,1,1));
                               counter1 = counter1 - 1;
                            end
                            
                        end
                        counter2 = 3;
                        counter1 = 0;
            
                        for j = 1:NPE
                            if mod(j,2) == 0
                                disp_y(j,i) = abs(disp(i,j+counter2,2,1));
                                counter2 = counter2 - 1;
                            else
                                disp_y(j,i) = abs(disp(i,j+counter1,2,1));
                                counter1 = counter1 - 1;
                            end
                        end
                    end
                case 6
                    NPE = NPE/2;
                    for i = 1:NoE
                        nl  = EL(i,1:NPE);
                        for j = 1:NPE %Coordinates of the corner of element [add disp to initial coord]
                            X(j,i) = ENL(nl(j),1) + scale*ENL(nl(j),4*PD+1); %x disp j,i to make them columnwise
                            Y(j,i) = ENL(nl(j),2) + scale*ENL(nl(j),4*PD+2); %y disp
                        end
                        
                        for j = 1:NPE
                            val = stress(i,:,1,1); %: %:[':' represents all the gausspoints] 1 by 4 matrix,4 values corresponding to xx component of stress at each points 
                            stress_xx(j,i) = val(1,j); %: j,i to store columnwise
            
                        end

                        for j = 1:NPE
                            val = stress(i,:,1,2); 
                            stress_xy(j,i) = val(1,j);
                        end
                        for j = 1:NPE
                            val = stress(i,:,2,1); 
                            stress_yx(j,i) = val(1,j);
                        end
                        for j = 1:NPE
                            val = stress(i,:,2,2); 
                            stress_yy(j,i) = val(1,j);
                        end

                        %Strain
                         for j = 1:NPE
                            val = strain(i,:,1,1); 
                            strain_xx(j,i) = val(1,j);  
                        end
            
                        for j = 1:NPE
                            val = strain(i,:,1,2); 
                            strain_xy(j,i) = val(1,j);
                        end
                        for j = 1:NPE
                            val = strain(i,:,2,1); 
                            strain_yx(j,i) = val(1,j);
                        end
                        for j = 1:NPE
                            val = strain(i,:,2,2); 
                            strain_yy(j,i) = val(1,j);
                        end
            
                        %Displacement
                        for j = 1:NPE
                            disp_x(j,i) = disp(i,j,1,1);
                        end
            
                        for j = 1:NPE
                            disp_y(j,i) = disp(i,j,2,1);
                        end
                    end
            end
            app.uStress_xx=stress_xx;
            app.uStress_xy = stress_xy;
            app.uStress_yx = stress_yx;
            app.uStress_yy = stress_yy;
            app.uStrain_xx = strain_xx;
            app.uStrain_xy = strain_xy;
            app.uStrain_yx = strain_yx;
            app.uStrain_yy = strain_yy;
            app.uDisplacement_x = disp_x;
            app.uDisplacement_y = disp_y;
            app.uX = X;
            app.uY = Y;
            post_process_graph(app,stress_xx,stress_xy,stress_yx,stress_yy,strain_xx,strain_xy,strain_yx,strain_yy,disp_x,disp_y,X,Y,indicator);
            
        end

        %plots the post process graph
        function [] = post_process_graph(app,stress_xx,stress_xy,stress_yx,stress_yy,strain_xx,strain_xy,strain_yx,strain_yy,disp_x,disp_y,X,Y,indicator)
            if isequal(indicator,'Stress xx')

                patch(app.PostProcessAxis,X,Y,stress_xx,'FaceColor','interp','LineStyle',app.displayEdges);
                title(app.PostProcessAxis,'Distribution of stress xx','FontWeight', 'bold');
                
                axis (app.PostProcessAxis, 'equal');
                colorBar(app);
            
            elseif isequal(indicator,'Stress xy')
                patch(app.PostProcessAxis ,X,Y,stress_xy,'FaceColor','interp','LineStyle',app.displayEdges);
                title(app.PostProcessAxis ,'Distribution of stress xy','FontWeight', 'bold');
                
                axis (app.PostProcessAxis, 'equal');
                colorBar (app);
            
            elseif isequal(indicator,'Stress yx')
                patch(app.PostProcessAxis ,X,Y,stress_yx,'FaceColor','interp','LineStyle',app.displayEdges);
                title(app.PostProcessAxis ,'Distribution of stress yx','FontWeight', 'bold','LineStyle',app.displayEdges);
                
                axis (app.PostProcessAxis, 'equal');
                colorBar(app);
            
            elseif isequal(indicator,'Stress yy')
                patch(app.PostProcessAxis ,X,Y,stress_yy,'FaceColor','interp','LineStyle',app.displayEdges);
                title(app.PostProcessAxis ,'Distribution of stress yy','FontWeight', 'bold');
                
                axis (app.PostProcessAxis, 'equal');
                colorBar(app);
            
            elseif isequal(indicator,'Strain xx')
                patch(app.PostProcessAxis ,X,Y,strain_xx,'FaceColor','interp','LineStyle',app.displayEdges);
                title(app.PostProcessAxis ,'Distribution of strain xx','FontWeight', 'bold');
                
                axis (app.PostProcessAxis, 'equal');
                colorBar(app);
            
            elseif isequal(indicator,'Strain xy')
                patch(app.PostProcessAxis ,X,Y,strain_xy,'FaceColor','interp','LineStyle',app.displayEdges);
                title(app.PostProcessAxis ,'Distribution of strain xy','FontWeight', 'bold');
                
                axis (app.PostProcessAxis, 'equal');
                colorBar(app);
            
            elseif isequal(indicator,'Strain yx')
                patch(app.PostProcessAxis ,X,Y,strain_yx,'FaceColor','interp','LineStyle',app.displayEdges);
                title(app.PostProcessAxis ,'Distribution of strain yx','FontWeight', 'bold');
                
                axis (app.PostProcessAxis, 'equal');
                colorBar(app);
            
            elseif isequal(indicator,'Strain yy')
                patch(app.PostProcessAxis ,X,Y,strain_yy,'FaceColor','interp','LineStyle',app.displayEdges);
                title(app.PostProcessAxis ,'Distribution of strain yy','FontWeight', 'bold');
                
                axis (app.PostProcessAxis, 'equal');
                colorBar(app);
            
            elseif isequal(indicator,'Displacement x')
                patch(app.PostProcessAxis ,X,Y,abs(disp_x),'FaceColor','interp','LineStyle',app.displayEdges);
                title(app.PostProcessAxis ,'Distribution of displacement x','FontWeight', 'bold');
                
                axis (app.PostProcessAxis, 'equal');
                colorBar(app);
            
            elseif isequal(indicator,'Displacement y')
                patch(app.PostProcessAxis , X,Y,abs(disp_y),'FaceColor','interp','LineStyle',app.displayEdges);
                title(app.PostProcessAxis ,'Distribution of displacement y','FontWeight', 'bold');
                
                axis(app.PostProcessAxis, 'equal');
                colorBar(app);
            end
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%% Color Map %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function []= colorBar(app)
            value=app.colorMap;
            if isequal(value,'jet')
                colormap(app.PostProcessAxis,'jet');
                axis(app.PostProcessAxis, 'equal');
                colorbar(app.PostProcessAxis);
            elseif isequal(value,'cool')
                colormap(app.PostProcessAxis,'cool');
                axis(app.PostProcessAxis, 'equal');
                colorbar(app.PostProcessAxis);
            elseif isequal(value,'copper')
                colormap(app.PostProcessAxis,'copper');
                axis(app.PostProcessAxis, 'equal');
                colorbar(app.PostProcessAxis);
            end
        end

        
        function [disp,stress,strain] = element_post_process(app,NL,EL,ENL,inclusion,NoE_void)
            
            NoN = size(NL,1);
            PD = size(NL,2);
            
            NoE = size(EL,1);
            NPE = size(EL,2);
            
            if (NPE == 3)
                GPE = 1;
            elseif ( NPE == 4 ) 
                GPE = 4;
            elseif ( NPE == 6 )
                GPE = 3;
            elseif ( NPE == 8 )
                GPE = 9;
            elseif ( NPE == 9 )
                GPE = 9;
            end

            disp = zeros(NoE,NPE,PD,1); 
            stress = zeros(NoE,GPE,PD,PD); 
            strain = zeros(NoE,GPE,PD,PD); 
            
            for e=1:NoE %loop over elements
                element_number = e;
            
                nl = EL(e,1:NPE); %Corner of the elements
            
                for i = 1:NPE %assign displacement
                    for j = 1:PD
                        disp(e,i,j,1) = ENL(nl(i),4*PD+j); %defining displacement over the domain
                    end
                end

                x = zeros(NPE,PD); %coordinates of the corners of element
                for i = 1:NPE
                    for j = 1:PD
                        x(i,j) = NL(nl(i),j);
                    end
                end     

                coor = x'; %Transpose of coordinates
                u = zeros(PD,NPE); 
                for i = 1:NPE
                    for j = 1:PD
                        u(j,i) = ENL(nl(i),4*PD+j);
                    end
                end
                
                for gp = 1:GPE
                    epsilon = zeros(PD,PD); %train for each gauss point 2x2 matrix
                    for i = 1: NPE
                        J = zeros(PD,PD);
                        grad = zeros(PD,NPE); %grad = Nix
                        [xi , eta , alpha] = GaussPoint(app,NPE,GPE,gp);
                        grad_nat = grad_N_nat(app,NPE, xi,eta);
                        J = coor * grad_nat' ;
                        grad= inv(J)' * grad_nat;
                        epsilon = epsilon + 1/2 * (dyad(app, grad(:,i),u(:,i)) + dyad(app,u(:,i),grad(:,i)));
                    end
                            
                     sigma = zeros(PD,PD);
                     for a= 1:PD
                         for b = 1:PD
                             for c = 1:PD
                                 for d = 1:PD
                                     sigma(a,b) = sigma(a,b) + constitutive(app,a,b,c,d,inclusion,element_number,NoE_void)*epsilon(c,d);
                                 end
                             end
                         end
                     end
                     for a = 1:PD
                         for b = 1:PD
                                strain(e,gp,a,b) = epsilon(a,b);
                                stress(e,gp,a,b) = sigma(a,b);
            %                    strain(e,gp,:,:) = epsilon(a,b);
            %                    stress(e,gp,:,:) = sigma(a,b);
                         end
                     end
                end 
            end
        end
        
        function A = dyad(app,u,v)
            PD = 2;
            A = zeros(PD,PD);
            A = u(1:PD) .*transpose( v(1:PD));
            end 
        
        function [] = RUN_value_check(app)
            wrongv = 0 ;
            errortext = "";

            if app.Matrixv.Value >0.5
                wrongv = 1;
                app.Matrixv.Value = 0.5;
            end

            if app.inc && app.Incv.Value > 0.5
                    wrongv =1;
                    app.Incv.Value = 0.5;

            end
            if wrongv
                errortext = errortext + "v values can be 0.5 at most" + newline;
                errortext = errortext + "values are set to the max possible value";
                app.ErrorField.Text = errortext;

            end

            
        end
        
        function results = Generate_value_check(app)
            results = 0;
            if(app.aEditField.Value>0)&&(app.bEditField.Value>0)&&(app.cEditField.Value>0)&&(app.dEditField.Value>0)&&(app.eEditField.Value>0)&&(app.aEditField.Value>2*app.eEditField.Value)&&(app.bEditField.Value>2*app.eEditField.Value)
                results = 1;
            elseif app.aEditField.Value<=0 || app.bEditField.Value<=0 || app.cEditField.Value<=0 || app.dEditField.Value<=0 || app.eEditField.Value<=0
                app.ErrorField.Text = "Dimensions must be greater than zero";
            elseif app.aEditField.Value<=2*app.eEditField.Value
                app.ErrorField.Text = "a > 2e condition not met";
            elseif app.bEditField.Value< 2*app.eEditField.Value
                app.ErrorField.Text = "a > 2e condition not met";
            end

        end
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: InclusionShapeButtonGroup
        function InclusionShapeButtonGroupSelectionChanged(app, event)
            selectedButton = app.InclusionShapeButtonGroup.SelectedObject;
            
            switch selectedButton
                case app.CircleButton
                    app.ShapeImage.ImageSource = 'Circle.png';
                    app.inclusionShape = "circle" ;

                case app.SquareButton
                    app.ShapeImage.ImageSource = 'Square.png';
                    app.inclusionShape = "square" ;

                case app.RhombusButton
                    app.ShapeImage.ImageSource = 'Rhombus.png';
                    app.inclusionShape = "rhombus" ;
            end

            if app.runDone == "True"
                app.Lamp.Color = [1.00,0.00,0.00];
            end

        end

        % Selection changed function: DeformationTypeButtonGroup
        function DeformationTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.DeformationTypeButtonGroup.SelectedObject;

            switch selectedButton
                case app.ShearButton
                    app.DeformationTypeImage.ImageSource = 'Shear.png';
                    app.BC_type = "Shear" ;

                case app.ExpansionButton
                    app.DeformationTypeImage.ImageSource = 'Expansion.png';
                    app.BC_type = "Expansion" ;

                case app.ExtensionButton
                    app.DeformationTypeImage.ImageSource = 'Extension.png';
                    app.BC_type = "Extension" ;
            end

            if app.runDone == "True"
                app.Lamp.Color = [0.94,0.72,0.00];
            end
            
        end

        % Value changed function: IncSwitch
        function IncSwitchValueChanged(app, event)
            value = app.IncSwitch.Value;
            if app.runDone == "True" | app.generateDone == "True" 
                app.Lamp.Color = [1.00,0.00,0.00];
            end

            if isequal(value,"Void")
                app.inc = 0;
                drawnow()
                app.IncE.Editable='off'; 
                app.IncE.Visible='off';
                app.Incv.Editable='off';
                app.Incv.Visible='off'; 
            else
                app.inc=1;
                drawnow()
                app.IncE.Editable='on';
                app.IncE.Visible='on'; 
                app.Incv.Editable='on';
                app.Incv.Visible='on'; 

            end
        end

        % Value changed function: ElementType
        function ElementTypeValueChanged(app, event)
           
            if app.runDone == "True" | app.generateDone == "True" 
                app.Lamp.Color = [1.00,0.00,0.00];
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            drawnow()
            app.ErrorField.Text = " ";
            app.Lamp.Color = [0.94,0.72,0.00];
            app.generateDone = "True";
            valuecheck = Generate_value_check(app);
            if valuecheck ==1
            hold(app.PreProcessAxis,"off");
            cla (app.PreProcessAxis,"reset");
            axis(app.PreProcessAxis, "equal") ;
            inclusion=app.inc;
            NoE=0;
            NPE=0;
            NoN=0;

            
            if isequal(app.inclusionShape,'circle')
                [tempNL,tempEL] = void_mesh_circle(app.aEditField.Value,app.bEditField.Value,app.cEditField.Value,app.dEditField.Value,app.eEditField.Value,app.ElementType.Value,inclusion);
                NoE=size(tempEL,1);
                NPE=size(tempEL,2);
                NoN=size(tempNL,1);
            elseif isequal(app.inclusionShape,'square')
                [tempNL,tempEL] = void_mesh_square(app.aEditField.Value,app.bEditField.Value,app.cEditField.Value,app.dEditField.Value,app.eEditField.Value,app.ElementType.Value,inclusion);
                NoE=size(tempEL,1);
                NPE=size(tempEL,2);
                NoN=size(tempNL,1);
            elseif isequal(app.inclusionShape,'rhombus')
                [tempNL,tempEL] = void_mesh_rhombus(app.aEditField.Value,app.bEditField.Value,app.cEditField.Value,app.dEditField.Value,app.eEditField.Value,app.ElementType.Value,inclusion);
                NoE=size(tempEL,1);
                NPE=size(tempEL,2);
                NoN=size(tempNL,1);
                
            end
            if NPE==8 || NPE==9
                NPE2=4;
                for i = 1:NoE
                nl  = tempEL(i,1:NPE2);
                    for j = 1:NPE2 
                        X1(j,i) = tempNL(nl(j),1);  
                        Y1(j,i) = tempNL(nl(j),2);
                    end
                end
                patch(app.PreProcessAxis,X1,Y1,0,'FaceColor','none'); 
%                 set(app.PreProcessAxis, 'visible','off')
%                 set(app.PreProcessAxis, 'xtick',[])
%                 set(app.PreProcessAxis,'Color','black');
            elseif NPE==6
                NPE3=3;
                for i = 1:NoE
                
                nl  = tempEL(i,1:NPE3);
            
                    for j = 1:NPE3 
                      X1(j,i) = tempNL(nl(j),1);  
                      Y1(j,i) = tempNL(nl(j),2);
                    end
                end
                patch(app.PreProcessAxis,X1,Y1,0,'FaceColor','none'); 
%                 set(app.PreProcessAxis, 'visible','off')
%                 set(app.PreProcessAxis, 'xtick',[])
%                 set(app.PreProcessAxis,'Color','black');   
            else
                for i = 1:NoE
                nl  = tempEL(i,1:NPE);
                    for j = 1:NPE 
                      X1(j,i) = tempNL(nl(j),1);  
                      Y1(j,i) = tempNL(nl(j),2);
                    end
                end
                patch(app.PreProcessAxis,X1,Y1,0,'FaceColor','none');
%                 set(app.PreProcessAxis, 'visible','off')
%                 set(app.PreProcessAxis, 'xtick',[])
%                 set(app.PreProcessAxis,'Color','black');
            end
            
            app.NodesEditField.Value=NoN;
            app.ElementsEditField.Value=NoE;
            app.EL=tempEL;
            app.NL=tempNL;
            app.inc=inclusion;
            
            if inclusion
                app.IncE.Editable='on';
                app.IncE.Visible='on'; 
                
                app.Incv.Editable='on';
                app.Incv.Visible='on'; 
            
            else
                app.IncE.Editable='off'; 
                app.Incv.Editable='off'; 

            end
                   
                   
            else
                app.Lamp.Color = [1.00,0.00,0.00];
                app.generateDone = "False";
                   
            end
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
           if app.runDone == "True" | app.generateDone == "True" 
                app.Lamp.Color = [1.00,0.00,0.00];
            end
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            if app.runDone == "True" | app.generateDone == "True" 
                app.Lamp.Color = [1.00,0.00,0.00];
            end
        end

        % Value changed function: cEditField
        function cEditFieldValueChanged(app, event)

            if app.runDone == "True" | app.generateDone == "True" 
                app.Lamp.Color = [1.00,0.00,0.00];
            end
        end

        % Value changed function: dEditField
        function dEditFieldValueChanged(app, event)
            if app.runDone == "True" | app.generateDone == "True" 
                app.Lamp.Color = [1.00,0.00,0.00];
            end
        end

        % Value changed function: eEditField
        function eEditFieldValueChanged(app, event)
            if app.runDone == "True" | app.generateDone == "True" 
                app.Lamp.Color = [1.00,0.00,0.00];
            end
        end

        % Value changed function: MatrixE
        function MatrixEValueChanged(app, event)
            if app.runDone == "True"  
                app.Lamp.Color = [0.94,0.72,0.00];
            end
        end

        % Value changed function: Matrixv
        function MatrixvValueChanged(app, event)
            if app.runDone == "True"  
                app.Lamp.Color = [0.94,0.72,0.00];
            end
        end

        % Value changed function: IncE
        function IncEValueChanged(app, event)
            if app.runDone == "True"  
                app.Lamp.Color = [0.94,0.72,0.00];
            end
        end

        % Value changed function: Incv
        function IncvValueChanged(app, event)
            if app.runDone == "True"  
                app.Lamp.Color = [0.94,0.72,0.00];
            end
        end

        % Value changed function: DeformationMagnitude
        function DeformationMagnitudeValueChanged(app, event)
            if app.runDone == "True"  
                app.Lamp.Color = [0.94,0.72,0.00];
            end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            app.Lamp.Color = [0.65,0.65,0.65];
            drawnow()
            app.ErrorField.Text = " ";
            inclusion=app.inc;
            if inclusion && (app.IncE.Value<=0 || app.Incv.Value<=0)
                app.Lamp.Color = [0.94,0.72,0.00];
                app.ErrorField.Text = "Please enter valid inclusion values." + newline + "(E and v values must be greater than zero)";
                drawnow()

            elseif isequal(app.NL,[]) ||isequal(app.EL,[]) || app.MatrixE.Value<=0 || app.Matrixv.Value<=0
                app.Lamp.Color = [1.00,0.00,0.00];
                drawnow()
                app.ErrorField.Text="Please generate mesh before running";
%                 app.E_void.Value=1;
%                 app.IncE.Value=0;
%                 app.nu_void.Value=0.3;
%                 app.nu_inclusion.Value=0;
            else
                RUN_value_check(app);
                hold(app.PostProcessAxis,"off");
                cla (app.PostProcessAxis,"reset");
                axis(app.PostProcessAxis, "equal") ;
                app.PostProcessAxis.Visible='on'; 
                app.ColormapGroup.Visible='on';     
                [tempENL,DOFs,DOCs] = assign_BCs( app,app.NL , app.BC_type);
                tempNL=app.NL;
                tempEL=app.EL;
                
                inclusion=app.inc;
                app.postNL = tempNL ;
                app.postEL = tempEL;
                app.postc = app.cEditField.Value;
                app.postd = app.dEditField.Value;
                app.postinc = inclusion;
                
                
                K = assemble_stiffness( app,tempENL , tempEL , tempNL , app.cEditField.Value , app.dEditField.Value , inclusion ) ; %Stiffness 
                
                Fp = assemble_forces(tempENL,tempNL); %Prescribed Forces
                
                Up = assemble_displacements(tempENL,tempNL); %Prescribed Displacements
                %Calculate the reduced stiffness matrix using static condensation
                K_reduced = K(1:DOFs, 1:DOFs);
                
                F = Fp - K(1:DOFs, DOFs+1:(DOFs+DOCs)) * Up;
                
                Uu = K_reduced \ F; 
                %Uu = inv(K_reduced) * F
                
                Fu = K(DOFs+1:DOFs+DOCs,1:DOFs)*Uu  +  K(DOFs+1:DOFs+DOCs,DOFs+1:DOFs+DOCs)*Up ;
                
                tempENL = update_nodes(tempENL,Uu,Fu,tempNL);
                app.ENL=tempENL;
                
                post_process(app,tempNL,tempEL,tempENL,app.cEditField.Value,app.dEditField.Value,inclusion,app.DisplayValuesDropDown.Value);
%                 set(app.PostProcessAxis, 'visible','off')
%                 set(app.PostProcessAxis, 'xtick',[])
%                 set(app.PostProcessAxis,'Color','black');

                app.E_void_run=app.MatrixE.Value;
                app.nu_void_run=app.Matrixv.Value;
                app.E_inc_run=app.IncE.Value;
                app.nu_inc_run=app.Incv.Value;
                
                app.Lamp.Color = [0.21,0.91,0.21];
                app.runDone = "True" ;
                drawnow()
            end
        end

        % Selection changed function: ColormapGroup
        function ColormapGroupSelectionChanged(app, event)
            selectedButton = app.ColormapGroup.SelectedObject;
            
            switch selectedButton
                case app.Copper
                    app.MatrixE.Value=app.E_void_run;
                    app.IncE.Value=app.E_inc_run;
                    app.Matrixv.Value=app.nu_void_run;
                    app.Incv.Value=app.nu_inc_run;
                    if isequal(app.NL,[]) ||isequal(app.EL,[]) || app.MatrixE.Value<=0 || app.Matrixv.Value<=0
                        app.MatrixE.Value=1;
                        app.IncE.Value=0;
                        app.Matrixv.Value=0.3;
                        app.Incv.Value=0;
                    elseif isequal(app.ENL,[])    
                    else
                        app.colorMap='copper';
                        hold(app.PostProcessAxis,"off");
                        cla (app.PostProcessAxis,"reset");
                        axis(app.PostProcessAxis, "equal") ;
%                         set(app.PostProcessAxis, 'visible','off')
%                         set(app.PostProcessAxis, 'xtick',[])
%                         set(app.PostProcessAxis,'Color','black');
                        post_process_graph(app, app.uStress_xx, app.uStress_xy, app.uStress_yx, app.uStress_yy, app.uStrain_xx, app.uStrain_xy, app.uStrain_yx, app.uStrain_yy, app.uDisplacement_x, app.uDisplacement_y, app.uX, app.uY, app.DisplayValuesDropDown.Value);
                    end

                case app.Cool
                    app.MatrixE.Value=app.E_void_run;
                    app.IncE.Value=app.E_inc_run;
                    app.Matrixv.Value=app.nu_void_run;
                    app.Incv.Value=app.nu_inc_run;
                    if isequal(app.NL,[]) ||isequal(app.EL,[]) || app.MatrixE.Value<=0 || app.Matrixv.Value<=0
                        app.MatrixE.Value=1;
                        app.IncE.Value=0;
                        app.Matrixv.Value=0.3;
                        app.Incv.Value=0;
                    elseif isequal(app.ENL,[])    
                    else
                        app.colorMap='cool';
                        hold(app.PostProcessAxis,"off");
                        cla (app.PostProcessAxis,"reset");
                        axis(app.PostProcessAxis, "equal") ;
%                         set(app.PostProcessAxis, 'visible','off')
%                         set(app.PostProcessAxis, 'xtick',[])
%                         set(app.PostProcessAxis,'Color','black');
                        post_process_graph(app, app.uStress_xx, app.uStress_xy, app.uStress_yx, app.uStress_yy, app.uStrain_xx, app.uStrain_xy, app.uStrain_yx, app.uStrain_yy, app.uDisplacement_x, app.uDisplacement_y, app.uX, app.uY, app.DisplayValuesDropDown.Value);
                    end

                case app.Jet
                    app.MatrixE.Value=app.E_void_run;
                    app.IncE.Value=app.E_inc_run;
                    app.Matrixv.Value=app.nu_void_run;
                    app.Incv.Value=app.nu_inc_run;
                    if isequal(app.NL,[]) ||isequal(app.EL,[]) || app.MatrixE.Value<=0 || app.Matrixv.Value<=0
                        app.MatrixE.Value=1;
                        app.IncE.Value=0;
                        app.Matrixv.Value=0.3;
                        app.Incv.Value=0;
                    elseif isequal(app.ENL,[])    
                    else
                        app.colorMap='jet';
                        hold(app.PostProcessAxis,"off");
                        cla (app.PostProcessAxis,"reset");
                        axis(app.PostProcessAxis, "equal") ;
%                         set(app.PostProcessAxis, 'visible','off')
%                         set(app.PostProcessAxis, 'xtick',[])
%                         set(app.PostProcessAxis,'Color','black');
                        post_process_graph(app, app.uStress_xx, app.uStress_xy, app.uStress_yx, app.uStress_yy, app.uStrain_xx, app.uStrain_xy, app.uStrain_yx, app.uStrain_yy, app.uDisplacement_x, app.uDisplacement_y, app.uX, app.uY, app.DisplayValuesDropDown.Value);
                    end
            end

            if app.runDone == "True"
                app.Lamp.Color = [1.00,0.00,0.00];
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Lamp.Color = [1.00,0.00,0.00];
            drawnow()
            
            app.NodesEditField.Value=0;
            app.ElementsEditField.Value=0;
            app.EL=[];
            app.NL=[]; 
            
            
            hold(app.PreProcessAxis,"off");
            cla (app.PreProcessAxis,"reset");
            axis(app.PreProcessAxis, "equal") ;
%             set(app.PreProcessAxis, 'visible','off')
%             set(app.PreProcessAxis, 'xtick',[])
%             set(app.PreProcessAxis,'Color','black');
        end

        % Button pushed function: RESETALLButton
        function RESETALLButtonPushed(app, event)
            app.ENL=[];
            app.uStress_xx = [];
            app.uStress_xy = [];
            app.uStress_yx = [];
            app.uStress_yy = [];
            app.uStrain_xx = [];
            app.uStrain_xy = [];
            app.uStrain_yx = [];
            app.uStrain_yy = [];
            app.uDisplacement_x = [];
            app.uDisplacement_y = [];
            app.uX = [];
            app.uY = [];
            app.postNL = [];
            app.postEL = [];
            app.postc = 0;
            app.postd = 0;
            app.postinc = 0;             
    
            hold(app.PostProcessAxis,"off");
            cla (app.PostProcessAxis,"reset");
            axis(app.PostProcessAxis, "equal") ;
%             set(app.PostProcessAxis, 'visible','off')
%             set(app.PostProcessAxis, 'xtick',[])
%             set(app.PostProcessAxis,'Color','black');
            app.Lamp.Color = [1.00,0.00,0.00];
            drawnow()
            
            app.NodesEditField.Value=0;
            app.ElementsEditField.Value=0;
            app.EL=[];
            app.NL=[]; 
            
            hold(app.PreProcessAxis,"off");
            cla (app.PreProcessAxis,"reset");
            axis(app.PreProcessAxis, "equal") ;
%             set(app.PreProcessAxis, 'visible','off')
%             set(app.PreProcessAxis, 'xtick',[])
%             set(app.PreProcessAxis,'Color','black');
        end

        % Value changed function: DisplayEdgesSwitch
        function DisplayEdgesSwitchValueChanged(app, event)

                value = app.DisplayEdgesSwitch.Value;
                if isequal(value,'On')
                    app.displayEdges='-';
                else
                    app.displayEdges='none';
                end
                hold(app.PostProcessAxis,"off");
                cla (app.PostProcessAxis,"reset");
                axis(app.PostProcessAxis, "equal") ;
%                 set(app.PostProcessAxis, 'visible','off')
%                 set(app.PostProcessAxis, 'xtick',[])
%                 set(app.PostProcessAxis,'Color','black');
                post_process_graph(app, app.uStress_xx, app.uStress_xy, app.uStress_yx, app.uStress_yy, app.uStrain_xx, app.uStrain_xy, app.uStrain_yx, app.uStrain_yy, app.uDisplacement_x, app.uDisplacement_y, app.uX, app.uY, app.DisplayValuesDropDown.Value);
        end

        % Value changed function: DeformationScaleSlider
        function DeformationScaleSliderValueChanged(app, event)
            value = app.DeformationScaleSlider.Value;
            hold(app.PostProcessAxis,"off");
            cla (app.PostProcessAxis,"reset");
            axis(app.PostProcessAxis, "equal") ;
%             set(app.PostProcessAxis, 'visible','off')
%             set(app.PostProcessAxis, 'xtick',[])
%             set(app.PostProcessAxis,'Color','black');
            post_process(app,app.postNL,app.postEL,app.ENL,app.postc,app.postd,app.postinc,app.DisplayValuesDropDown.Value);
        end

        % Value changed function: DisplayValuesDropDown
        function DisplayValuesDropDownValueChanged(app, event)
            value = app.DisplayValuesDropDown.Value;
            hold(app.PostProcessAxis,"off");
            cla (app.PostProcessAxis,"reset");
            axis(app.PostProcessAxis, "equal") ;
%             set(app.PostProcessAxis, 'visible','off')
%             set(app.PostProcessAxis, 'xtick',[])
%             set(app.PostProcessAxis,'Color','black');
            post_process_graph(app, app.uStress_xx, app.uStress_xy, app.uStress_yx, app.uStress_yy, app.uStrain_xx, app.uStrain_xy, app.uStrain_yx, app.uStrain_yy, app.uDisplacement_x, app.uDisplacement_y, app.uX, app.uY, app.DisplayValuesDropDown.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ProcessTab = uitab(app.TabGroup);
            app.ProcessTab.Title = 'Process';
            app.GridLayout = uigridlayout(app.ProcessTab);
            app.PreProcessPanel = uipanel(app.GridLayout);
            app.PreProcessPanel.Title = 'Pre Process';
            app.GridLayout2 = uigridlayout(app.PreProcessPanel);
            app.PreProcessAxis = uiaxes(app.GridLayout2);
            app.PreProcessAxis.XLimitMethod = 'padded';
            app.PreProcessAxis.YLimitMethod = 'padded';
            app.PreProcessAxis.ZLimitMethod = 'padded';
            app.PreProcessAxis.XTick = [];
            app.PreProcessAxis.XTickLabel = '';
            app.PreProcessAxis.YTick = [];
            app.PreProcessAxis.YTickLabel = '';
            app.ShapeImage = uiimage(app.GridLayout2);
            app.ShapeImage.ImageSource = 'Circle.png';
            app.InclusionShapeButtonGroup = uibuttongroup(app.GridLayout2);
            app.InclusionShapeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InclusionShapeButtonGroupSelectionChanged, true);
            app.InclusionShapeButtonGroup.Title = 'Inclusion Shape';
            app.RhombusButton = uitogglebutton(app.InclusionShapeButtonGroup);
            app.RhombusButton.Text = 'Rhombus';
            app.SquareButton = uitogglebutton(app.InclusionShapeButtonGroup);
            app.SquareButton.Text = 'Square';
            app.CircleButton = uitogglebutton(app.InclusionShapeButtonGroup);
            app.CircleButton.Text = 'Circle';
            app.CircleButton.Value = true;
            app.ElementTypeDropDownLabel = uilabel(app.GridLayout2);
            app.ElementTypeDropDownLabel.Text = 'Element Type';
            app.ElementType = uidropdown(app.GridLayout2);
            app.ElementType.Items = {'D2TR3N', 'D2TR6N', 'D2QU4N', 'D2QU8N', 'D2QU9N'};
            app.ElementType.ValueChangedFcn = createCallbackFcn(app, @ElementTypeValueChanged, true);
            app.ElementType.Value = 'D2TR3N';
            app.GenerateButton = uibutton(app.GridLayout2, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ElementsEditFieldLabel = uilabel(app.GridLayout2);
            app.ElementsEditFieldLabel.Text = '#Elements';
            app.ElementsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ElementsEditField.Editable = 'off';
            app.NodesEditFieldLabel = uilabel(app.GridLayout2);
            app.NodesEditFieldLabel.Text = '#Nodes';
            app.NodesEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NodesEditField.Editable = 'off';
            app.ErrorField = uilabel(app.GridLayout2);
            app.ErrorField.Text = '';
            app.IncSwitch = uiswitch(app.GridLayout2, 'slider');
            app.IncSwitch.Items = {'Void', 'Inclusion'};
            app.IncSwitch.ValueChangedFcn = createCallbackFcn(app, @IncSwitchValueChanged, true);
            app.IncSwitch.Value = 'Inclusion';
            app.aEditFieldLabel = uilabel(app.GridLayout2);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.GridLayout2, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.aEditField.Value = 1;
            app.bEditFieldLabel = uilabel(app.GridLayout2);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.GridLayout2, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.bEditField.Value = 1;
            app.cEditFieldLabel = uilabel(app.GridLayout2);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.GridLayout2, 'numeric');
            app.cEditField.ValueChangedFcn = createCallbackFcn(app, @cEditFieldValueChanged, true);
            app.cEditField.Value = 9;
            app.dEditFieldLabel = uilabel(app.GridLayout2);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.GridLayout2, 'numeric');
            app.dEditField.ValueChangedFcn = createCallbackFcn(app, @dEditFieldValueChanged, true);
            app.dEditField.Value = 9;
            app.eEditFieldLabel = uilabel(app.GridLayout2);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.GridLayout2, 'numeric');
            app.eEditField.ValueChangedFcn = createCallbackFcn(app, @eEditFieldValueChanged, true);
            app.eEditField.Value = 0.2;
            app.DimensionsandNumberofPartitionsLabel = uilabel(app.GridLayout2);
            app.DimensionsandNumberofPartitionsLabel.Text = 'Dimensions and Number of Partitions';
            app.ProcessPanel = uipanel(app.GridLayout);
            app.ProcessPanel.Title = 'Process ';
            app.GridLayout3 = uigridlayout(app.ProcessPanel);
            app.MaterialPropertiesLabel = uilabel(app.GridLayout3);
            app.MaterialPropertiesLabel.Text = 'Material Properties';
            app.MatrixLabel = uilabel(app.GridLayout3);
            app.MatrixLabel.Text = 'Matrix';
            app.InclusionLabel = uilabel(app.GridLayout3);
            app.InclusionLabel.Text = 'Inclusion';
            app.EEditFieldLabel = uilabel(app.GridLayout3);
            app.EEditFieldLabel.Text = 'E';
            app.MatrixE = uieditfield(app.GridLayout3, 'numeric');
            app.MatrixE.ValueChangedFcn = createCallbackFcn(app, @MatrixEValueChanged, true);
            app.MatrixE.Value = 1;
            app.EEditField_2Label = uilabel(app.GridLayout3);
            app.EEditField_2Label.Text = 'E';
            app.IncE = uieditfield(app.GridLayout3, 'numeric');
            app.IncE.ValueChangedFcn = createCallbackFcn(app, @IncEValueChanged, true);
            app.vEditFieldLabel = uilabel(app.GridLayout3);
            app.vEditFieldLabel.Text = 'v';
            app.Matrixv = uieditfield(app.GridLayout3, 'numeric');
            app.Matrixv.ValueChangedFcn = createCallbackFcn(app, @MatrixvValueChanged, true);
            app.Matrixv.Value = 0.3;
            app.vEditField_2Label = uilabel(app.GridLayout3);
            app.vEditField_2Label.Text = 'v';
            app.Incv = uieditfield(app.GridLayout3, 'numeric');
            app.Incv.ValueChangedFcn = createCallbackFcn(app, @IncvValueChanged, true);
            app.DeformationTypeButtonGroup = uibuttongroup(app.GridLayout3);
            app.DeformationTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DeformationTypeButtonGroupSelectionChanged, true);
            app.DeformationTypeButtonGroup.Title = 'Deformation Type';
            app.ExtensionButton = uitogglebutton(app.DeformationTypeButtonGroup);
            app.ExtensionButton.Text = 'Extension';
            app.ExtensionButton.Value = true;
            app.ExpansionButton = uitogglebutton(app.DeformationTypeButtonGroup);
            app.ExpansionButton.Text = 'Expansion';
            app.ShearButton = uitogglebutton(app.DeformationTypeButtonGroup);
            app.ShearButton.Text = 'Shear';
            app.DeformationTypeImage = uiimage(app.GridLayout3);
            app.DeformationTypeImage.ImageSource = fullfile(pathToMLAPP, 'Extension.jpg');
            app.DeformationMagnitudeEditFieldLabel = uilabel(app.GridLayout3);
            app.DeformationMagnitudeEditFieldLabel.Text = {'Deformation'; 'Magnitude'};
            app.DeformationMagnitude = uieditfield(app.GridLayout3, 'numeric');
            app.DeformationMagnitude.ValueChangedFcn = createCallbackFcn(app, @DeformationMagnitudeValueChanged, true);
            app.DeformationMagnitude.Value = 0.1;
            app.RUNButton = uibutton(app.GridLayout3, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.Lamp = uilamp(app.GridLayout3);
            app.PostProcessTab = uitab(app.TabGroup);
            app.PostProcessTab.Title = 'Post-Process';
            app.GridLayout4 = uigridlayout(app.PostProcessTab);
            app.PostProcessPanel = uipanel(app.GridLayout4);
            app.PostProcessPanel.Title = 'Post Process';
            app.GridLayout5 = uigridlayout(app.PostProcessPanel);
            app.PostProcessAxis = uiaxes(app.GridLayout5);
            app.PostProcessAxis.XTick = [];
            app.PostProcessAxis.XTickLabel = '';
            app.PostProcessAxis.YTick = [];
            app.PostProcessAxis.YTickLabel = '';
            app.DeformationScaleSliderLabel = uilabel(app.GridLayout5);
            app.DeformationScaleSliderLabel.Text = {'Deformation'; 'Scale'};
            app.DeformationScaleSlider = uislider(app.GridLayout5);
            app.DeformationScaleSlider.Limits = [-2 2];
            app.DeformationScaleSlider.MajorTicks = [-2 -1 0 1 2];
            app.DeformationScaleSlider.MajorTickLabels = {'0.01', '0.1', '1', '10', '100'};
            app.DeformationScaleSlider.ValueChangedFcn = createCallbackFcn(app, @DeformationScaleSliderValueChanged, true);
            app.DeformationScaleSlider.MinorTicks = [];
            app.ColormapGroup = uibuttongroup(app.GridLayout5);
            app.ColormapGroup.SelectionChangedFcn = createCallbackFcn(app, @ColormapGroupSelectionChanged, true);
            app.ColormapGroup.Title = 'Colormap';
            app.Jet = uitogglebutton(app.ColormapGroup);
            app.Jet.Icon = fullfile(pathToMLAPP, 'jet.jpeg');
            app.Jet.Text = '';
            app.Jet.Value = true;
            app.Cool = uitogglebutton(app.ColormapGroup);
            app.Cool.Icon = fullfile(pathToMLAPP, 'cool.jpeg');
            app.Cool.Text = '';
            app.Copper = uitogglebutton(app.ColormapGroup);
            app.Copper.Icon = fullfile(pathToMLAPP, 'copper.jpeg');
            app.Copper.Text = '';
            app.RESETALLButton = uibutton(app.GridLayout5, 'push');
            app.RESETALLButton.ButtonPushedFcn = createCallbackFcn(app, @RESETALLButtonPushed, true);
            app.RESETALLButton.Text = 'RESET ALL';
            app.DisplayValuesDropDownLabel = uilabel(app.GridLayout5);
            app.DisplayValuesDropDownLabel.Text = 'Display Values';
            app.DisplayValuesDropDown = uidropdown(app.GridLayout5);
            app.DisplayValuesDropDown.Items = {'Stress xx', 'Stress xy', 'Stress yx', 'Stress yy', 'Strain xx', 'Strain xy', 'Strain yx', 'Strain yy', 'Displacement x', 'Displacement y'};
            app.DisplayValuesDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayValuesDropDownValueChanged, true);
            app.DisplayValuesDropDown.Value = 'Stress xx';
            app.DisplayEdgesSwitchLabel = uilabel(app.GridLayout5);
            app.DisplayEdgesSwitchLabel.Text = 'Display Edges';
            app.DisplayEdgesSwitch = uiswitch(app.GridLayout5, 'rocker');
            app.DisplayEdgesSwitch.ValueChangedFcn = createCallbackFcn(app, @DisplayEdgesSwitchValueChanged, true);
            app.DisplayEdgesSwitch.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end